classdef TwoDofManipulatorFKCalculationUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EndpointZVal                    matlab.ui.control.NumericEditField
        EndpointZValEditFieldLabel      matlab.ui.control.Label
        EndpointYVal                    matlab.ui.control.NumericEditField
        EndpointYValEditFieldLabel      matlab.ui.control.Label
        EndpointXVal                    matlab.ui.control.NumericEditField
        EndpointXValEditFieldLabel      matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        ArmJointAngle                   matlab.ui.control.Slider
        ArmJointAngleTheta2SliderLabel  matlab.ui.control.Label
        BaseJointAngle                  matlab.ui.control.Slider
        BaseJointAngleTheta1SliderLabel  matlab.ui.control.Label
        manipulatorPlot                 matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function RobotPlot = RobotPlot(app, Link1Length, Link2Length, theta1, theta2)
            PosExtract=[0; 0; 0; 1];
            L1=Link1Length;
            L2=Link2Length;
            
            % Base to Arm
            p12=[L1*cos(theta1); L1*sin(theta1); 0];
            Rot12=[cos(theta1) -sin(theta1)   0
                        sin(theta1)    cos(theta1)    0
                        0   0   1];
            T12=HomogeneousTransform(Rot12, p12, 1);
            
            % Arm to Endpoint
            p23=[L2*cos(theta2); L2*sin(theta2); 0];
            Rot23=[cos(theta2) -sin(theta2)   0
                        sin(theta2)    cos(theta2)    0
                        0   0   1];
            T23=HomogeneousTransform(Rot23, p23, 1);
            
            %Base to Endpoint
            T13=T12*T23;
            
            % Position of Endpoint according to Base Coordinate
            p13=T13*PosExtract;
            app.EndpointXVal.Value=p13(1,1)
            app.EndpointYVal.Value=p13(2,1)
            app.EndpointZVal.Value=p13(3,1)
            
            % plot
            p = plot(app.manipulatorPlot, [PosExtract(1,1), p12(1,1), p13(1,1)],[PosExtract(2,1), p12(2,1), p13(2,1)],'-o','LineWidth',3);
            p.MarkerFaceColor = [1 0.5 0];
            p.MarkerSize = 8;
            p.MarkerEdgeColor = [1 0.5 0]
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BaseJointAngle, BaseJointAngle
        function BaseJointAngleValueChanged(app, event)
            theta1=event.Value;
            theta2=app.ArmJointAngle.Value;
            RobotPlot(app, 50, 50, theta1, theta2)
        end

        % Callback function: ArmJointAngle, ArmJointAngle
        function ArmJointAngleValueChanged(app, event)
            theta1=app.BaseJointAngle.Value;
            theta2=event.Value;
            RobotPlot(app, 50, 50, theta1, theta2)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            theta1=app.BaseJointAngle.Value;
            theta2=app.ArmJointAngle.Value;
            RobotPlot(app, 50, 50, theta1, theta2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.manipulatorPlot = uiaxes(app.UIFigure);
            title(app.manipulatorPlot, {'2-DoF Manipulator'; 'Forward Kinematics'})
            xlabel(app.manipulatorPlot, 'X')
            ylabel(app.manipulatorPlot, 'Y')
            zlabel(app.manipulatorPlot, 'Z')
            app.manipulatorPlot.XLim = [-100 100];
            app.manipulatorPlot.YLim = [-100 100];
            app.manipulatorPlot.ZLim = [-100 100];
            app.BaseJointAngleTheta1SliderLabel = uilabel(app.UIFigure);
            app.BaseJointAngleTheta1SliderLabel.Text = 'Base Joint Angle (Theta 1)';
            app.BaseJointAngle = uislider(app.UIFigure);
            app.BaseJointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.BaseJointAngle.ValueChangedFcn = createCallbackFcn(app, @BaseJointAngleValueChanged, true);
            app.BaseJointAngle.ValueChangingFcn = createCallbackFcn(app, @BaseJointAngleValueChanged, true);
            app.ArmJointAngleTheta2SliderLabel = uilabel(app.UIFigure);
            app.ArmJointAngleTheta2SliderLabel.Text = 'Arm Joint Angle (Theta 2)';
            app.ArmJointAngle = uislider(app.UIFigure);
            app.ArmJointAngle.Limits = [-3.14159265358979 3.14159265358979];
            app.ArmJointAngle.ValueChangedFcn = createCallbackFcn(app, @ArmJointAngleValueChanged, true);
            app.ArmJointAngle.ValueChangingFcn = createCallbackFcn(app, @ArmJointAngleValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndpointXValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointXValEditFieldLabel.Text = 'Endpoint X Val';
            app.EndpointXVal = uieditfield(app.UIFigure, 'numeric');
            app.EndpointYValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointYValEditFieldLabel.Text = 'Endpoint Y Val';
            app.EndpointYVal = uieditfield(app.UIFigure, 'numeric');
            app.EndpointZValEditFieldLabel = uilabel(app.UIFigure);
            app.EndpointZValEditFieldLabel.Text = 'Endpoint Z Val';
            app.EndpointZVal = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoDofManipulatorFKCalculationUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end